classdef mainApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        NumberLabel                matlab.ui.control.Label
        LicensePlateLabel          matlab.ui.control.Label
        DetectedLicensePlateLabel  matlab.ui.control.Label
        OriginalImageLabel         matlab.ui.control.Label
        UploadButton               matlab.ui.control.Button
        LicensePlateReaderLabel    matlab.ui.control.Label
        PlateImageAxes             matlab.ui.control.UIAxes
        OriginalImageAxes          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        oriImage % Original image, meant to be processed
    end
    
    methods (Access = private)
        function uploadPhoto(app)
            app.OriginalImageAxes.Visible = 'off';
            app.PlateImageAxes.Visible = 'off';
            app.OriginalImageLabel.Visible = 'off';
            app.DetectedLicensePlateLabel.Visible = 'off';
            app.LicensePlateLabel.Visible = 'off';
            app.NumberLabel.Visible = 'off';

            [filename,filepath] = uigetfile({'*.jpg;*.tif;*.png;*.gif'}, 'Select File to Open');
            if ischar(filepath)
                fullname = [filepath, filename];
            end
            ImageFile = imread(fullname);
            app.OriginalImageAxes.Visible = 'on';
            imagesc(app.OriginalImageAxes,ImageFile);
            app.oriImage = ImageFile;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Configure image axes
            app.OriginalImageAxes.Colormap = gray(256);
            axis(app.OriginalImageAxes, 'image');
            app.OriginalImageAxes.Visible = 'off';
            app.PlateImageAxes.Colormap = gray(256);
            axis(app.PlateImageAxes, 'image');
            app.PlateImageAxes.Visible = 'off';

            app.OriginalImageLabel.Visible = 'off';
            app.DetectedLicensePlateLabel.Visible = 'off';
            app.LicensePlateLabel.Visible = 'off';
            app.NumberLabel.Visible = 'off';

            Template_creation();
        end

        % Button pushed function: UploadButton
        function UploadButtonPushed(app, event)
            cla(app.OriginalImageAxes);
            cla(app.PlateImageAxes);

            uploadPhoto(app);

            [cropped_img, noPlate] = Plate_detection(app.oriImage);
            app.PlateImageAxes.Visible = 'on';
            imagesc(app.PlateImageAxes,cropped_img);

            app.OriginalImageLabel.Visible = 'on';
            app.DetectedLicensePlateLabel.Visible = 'on';
            app.LicensePlateLabel.Visible = 'on';
            app.NumberLabel.Text = noPlate;
            app.NumberLabel.Visible = 'on';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'License Plate Reader';
            app.OriginalImageAxes = uiaxes(app.UIFigure);
            app.OriginalImageAxes.XTick = [];
            app.OriginalImageAxes.XTickLabel = {'[ ]'};
            app.OriginalImageAxes.YTick = [];
            app.PlateImageAxes = uiaxes(app.UIFigure);
            app.PlateImageAxes.XTick = [];
            app.PlateImageAxes.XTickLabel = {'[ ]'};
            app.PlateImageAxes.YTick = [];
            app.LicensePlateReaderLabel = uilabel(app.UIFigure);
            app.LicensePlateReaderLabel.Text = 'License Plate Reader';
            app.UploadButton = uibutton(app.UIFigure, 'push');
            app.UploadButton.ButtonPushedFcn = createCallbackFcn(app, @UploadButtonPushed, true);
            app.UploadButton.Text = 'Upload image';
            app.OriginalImageLabel = uilabel(app.UIFigure);
            app.OriginalImageLabel.Visible = 'off';
            app.OriginalImageLabel.Text = 'Original Image';
            app.DetectedLicensePlateLabel = uilabel(app.UIFigure);
            app.DetectedLicensePlateLabel.Visible = 'off';
            app.DetectedLicensePlateLabel.Text = 'Detected License Plate';
            app.LicensePlateLabel = uilabel(app.UIFigure);
            app.LicensePlateLabel.Text = 'License Plate number:';
            app.NumberLabel = uilabel(app.UIFigure);
            app.NumberLabel.Visible = 'off';
            app.NumberLabel.Text = 'License here';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mainApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end